classdef App_v4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        GeneralTab                   matlab.ui.container.Tab
        CalculateInverseButton       matlab.ui.control.StateButton
        desired_z                    matlab.ui.control.NumericEditField
        zEditFieldLabel              matlab.ui.control.Label
        desired_y                    matlab.ui.control.NumericEditField
        yEditFieldLabel              matlab.ui.control.Label
        desired_x                    matlab.ui.control.NumericEditField
        xEditFieldLabel              matlab.ui.control.Label
        desired_gamma                matlab.ui.control.NumericEditField
        gammaEditFieldLabel          matlab.ui.control.Label
        desired_beta                 matlab.ui.control.NumericEditField
        betaEditFieldLabel           matlab.ui.control.Label
        desired_alpha                matlab.ui.control.NumericEditField
        alphaEditFieldLabel          matlab.ui.control.Label
        DesiredpositionandorientationLabel  matlab.ui.control.Label
        End_effector_table           matlab.ui.control.Table
        Jacobian_table               matlab.ui.control.Table
        EndeffectorspositionandorientationLabel  matlab.ui.control.Label
        JacobianLabel                matlab.ui.control.Label
        DHtablePanel                 matlab.ui.container.Panel
        ResetButton                  matlab.ui.control.StateButton
        calculate                    matlab.ui.control.Button
        Edit_J8                      matlab.ui.control.NumericEditField
        Edit_J7                      matlab.ui.control.NumericEditField
        Edit_J6                      matlab.ui.control.NumericEditField
        Edit_J5                      matlab.ui.control.NumericEditField
        Edit_J4                      matlab.ui.control.NumericEditField
        Edit_J3                      matlab.ui.control.NumericEditField
        Edit_J2                      matlab.ui.control.NumericEditField
        Edit_J1                      matlab.ui.control.NumericEditField
        Slider_J8                    matlab.ui.control.Slider
        Slider_J7                    matlab.ui.control.Slider
        Slider_J6                    matlab.ui.control.Slider
        Slider_J5                    matlab.ui.control.Slider
        Slider_J4                    matlab.ui.control.Slider
        Slider_J3                    matlab.ui.control.Slider
        Slider_J2                    matlab.ui.control.Slider
        Slider_J1                    matlab.ui.control.Slider
        Joint8                       matlab.ui.container.ButtonGroup
        NButton_8                    matlab.ui.control.RadioButton
        PButton_8                    matlab.ui.control.RadioButton
        RButton_8                    matlab.ui.control.RadioButton
        Joint7                       matlab.ui.container.ButtonGroup
        NButton_7                    matlab.ui.control.RadioButton
        PButton_7                    matlab.ui.control.RadioButton
        RButton_7                    matlab.ui.control.RadioButton
        Joint6                       matlab.ui.container.ButtonGroup
        NButton_6                    matlab.ui.control.RadioButton
        PButton_6                    matlab.ui.control.RadioButton
        RButton_6                    matlab.ui.control.RadioButton
        Joint5                       matlab.ui.container.ButtonGroup
        NButton_5                    matlab.ui.control.RadioButton
        PButton_5                    matlab.ui.control.RadioButton
        RButton_5                    matlab.ui.control.RadioButton
        Joint4                       matlab.ui.container.ButtonGroup
        NButton_4                    matlab.ui.control.RadioButton
        PButton_4                    matlab.ui.control.RadioButton
        RButton_4                    matlab.ui.control.RadioButton
        Joint3                       matlab.ui.container.ButtonGroup
        NButton_3                    matlab.ui.control.RadioButton
        PButton_3                    matlab.ui.control.RadioButton
        RButton_3                    matlab.ui.control.RadioButton
        Joint2                       matlab.ui.container.ButtonGroup
        NButton_2                    matlab.ui.control.RadioButton
        PButton_2                    matlab.ui.control.RadioButton
        RButton_2                    matlab.ui.control.RadioButton
        Joint1                       matlab.ui.container.ButtonGroup
        NButton_1                    matlab.ui.control.RadioButton
        PButton                      matlab.ui.control.RadioButton
        RButton                      matlab.ui.control.RadioButton
        DH_table                     matlab.ui.control.Table
        UIAxes                       matlab.ui.control.UIAxes
        KawasakiB006LTab             matlab.ui.container.Tab
        desired_z_kawasaki           matlab.ui.control.NumericEditField
        zEditFieldLabel_2            matlab.ui.control.Label
        desired_y_kawasaki           matlab.ui.control.NumericEditField
        yEditFieldLabel_2            matlab.ui.control.Label
        desired_x_kawasaki           matlab.ui.control.NumericEditField
        xEditFieldLabel_2            matlab.ui.control.Label
        desired_gamma_kawasaki       matlab.ui.control.NumericEditField
        gammaEditFieldLabel_2        matlab.ui.control.Label
        desired_beta_kawasaki        matlab.ui.control.NumericEditField
        betaEditFieldLabel_2         matlab.ui.control.Label
        desired_alpha_kawasaki       matlab.ui.control.NumericEditField
        alphaEditFieldLabel_2        matlab.ui.control.Label
        CalculateInverseButton_kawasaki  matlab.ui.control.StateButton
        DesiredpositionandorientationLabel_2  matlab.ui.control.Label
        Jacobian_table_Kawasaki      matlab.ui.control.Table
        JacobianLabel_2              matlab.ui.control.Label
        EndeffectorspositionandorientationLabel_2  matlab.ui.control.Label
        End_effector_table_Kawasaki  matlab.ui.control.Table
        DHtablePanel_Kawasaki        matlab.ui.container.Panel
        Knob_6                       matlab.ui.control.Knob
        Knob_6Label                  matlab.ui.control.Label
        Knob_5                       matlab.ui.control.Knob
        Knob_5Label                  matlab.ui.control.Label
        Knob_4                       matlab.ui.control.Knob
        Knob_4Label                  matlab.ui.control.Label
        Knob_3                       matlab.ui.control.Knob
        Knob_3Label                  matlab.ui.control.Label
        Knob_2                       matlab.ui.control.Knob
        Knob_2Label                  matlab.ui.control.Label
        Knob_1                       matlab.ui.control.Knob
        KnobLabel                    matlab.ui.control.Label
        upload                       matlab.ui.control.Button
        Edit_J6_kawasaki             matlab.ui.control.NumericEditField
        Edit_J5_kawasaki             matlab.ui.control.NumericEditField
        Edit_J4_kawasaki             matlab.ui.control.NumericEditField
        Edit_J3_kawasaki             matlab.ui.control.NumericEditField
        Edit_J2_kawasaki             matlab.ui.control.NumericEditField
        Edit_J1_kawasaki             matlab.ui.control.NumericEditField
        DH_table_kawasaki            matlab.ui.control.Table
        UIAxes_Kawasaki              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Description
        TMs
        GTMs  % all transformation matrices relative to the global frame (01,02,03,...,0N)
        joint_conf
        Jacobian
        desired_point
        end_effector_pos
        counter = 0
        Kawasaki_DH
        tab_name = 'General'
    end

    
    methods (Access = private)
        % Forward_Kinematics computes the Denavit-Hartenberg transformation matrix
        function T_k = forward_kinematics(app)
            % % Given:
        % %   a (i-1) - perpendicular distance between Z(i-1) and Z(i)
        % %                             
        % %
        % %   alpha(i-1) - angle from z(i-1) to z(i) about x(i)
        % %
        % %   d(i) - the link offset betwen origin(i) with respect to origin(i-1)
        % %     along z(i-1)
        % %
        % %   theta (i) - joint angle between from x(i-1) to x(i) about z(i-1)
        % 
            % Инициализация матрицы преобразования от базы до текущего сустава
            T_k = eye(4);
            if strcmp(app.tab_name,'General')
                joint_types = zeros(length(app.DH_table.Data),1);
                %Transformation Matirces
                Transformation_matrices = cell(1,length(app.DH_table.Data));
                Global_frame_TMs = cell(1,length(app.DH_table.Data));
                % Извлекаем параметры Денавита-Хартенберга для i-го звена
                DH_parameters = app.DH_table.Data;
                len = length(app.DH_table.Data);
            else
                Transformation_matrices = cell(1,length(app.Kawasaki_DH));
                Global_frame_TMs = cell(1,length(app.Kawasaki_DH));
                % Извлекаем параметры Денавита-Хартенберга для i-го звена
                DH_parameters = app.Kawasaki_DH;
                len = length(app.Kawasaki_DH);
            end
            q = get_edit_fields(app)';

            for i = 1:len
                if strcmp(app.tab_name,'General')
                    joint_types(i) = radiobutton_state(app,i);
                else
                    joint_types = [1 1 1 1 1 1]';
                end
                alpha = DH_parameters(i, 1);
                a = DH_parameters(i, 2);
                d = DH_parameters(i, 3);
                theta = DH_parameters(i, 4);
        
                % Определяем угол вращения или смещение в зависимости от типа сустава
                % state = 1 -> R; state = 2 -> P; state = 0 -> None
                if joint_types(i) == 1
                    % Вращательное звено: угол вращения звена равен q(i) плюс офсет theta из таблицы DH
                    theta = q(i) + theta;
                elseif joint_types(i) == 2
                    % Призматическое звено: смещение звена увеличивается на q(i) относительно начального смещения d из таблицы DH
                    d = d + q(i);
                % Для нейтрального звена 'N' значения из DH_parameters используются напрямую
                end
        
                % Умножение соответствующих матриц для получения матрицы преобразования для текущего звена
                T_current = [cosd(theta)            -sind(theta)            0            a;
                        sind(theta)*cosd(alpha)  cosd(theta)*cosd(alpha)  -sind(alpha)  -sind(alpha)*d;
                        sind(theta)*sind(alpha)  cosd(theta)*sind(alpha)  cosd(alpha)   cosd(alpha)*d;
                        0                       0                       0               1];
                
                % Обновление окончательной матрицы преобразования
                T_k = T_k * T_current;
                Transformation_matrices{i} = T_current;
                Global_frame_TMs{i} = T_k;
            end
            assignin('base', 'TMs',Transformation_matrices);  % to Workspace
            assignin('base', 'GTMs',Global_frame_TMs);  % to Workspace
            assignin('base', 'joint_types',joint_types);  % to Workspace
            app.TMs = Transformation_matrices;
            app.GTMs = Global_frame_TMs;
            app.joint_conf = joint_types;
        end


        function plot_graph(app, points)
            fig = app.UIFigure;
            fig.Name = 'Robot Project';
            
            
            if strcmp(app.tab_name,'General')
                graph = app.UIAxes;
            else
                graph = app.UIAxes_Kawasaki;
            end 
            % 3D plot
            if app.counter == 1
                plot3(graph, points(1, :), points(2, :), points(3, :), 'o-', ...
                    app.end_effector_pos(1, :),app.end_effector_pos(2, :), app.end_effector_pos(1,:),'LineWidth',2);
            else
                plot3(graph, points(1, :), points(2, :), points(3, :), 'o-', 'LineWidth',2);
            end
            grid(graph, 'on');
            title(graph, 'Robot');
            % hold on;
            % plot3(app.UIAxes,app.desired_x.Value,app.desired_y.Value,app.desired_z.Value,'LineWidth',3,'Color',[0 0.4470 0.8250]);
        end

        function points = calculate_joint_points(app)
            initial_point = [0 0 0 1]';
            points = initial_point;
            % if strcmp(app.tab_name,'General')
            for i = 1:size(app.TMs,2)
                new_point = app.GTMs{i} * initial_point;
                % assignin('base', 'new_point', new_point);
                % assignin('base', 'point_end', points(:,end));
                points(:, end + 1) = new_point;
            end
        end
        
        function state = radiobutton_state(app,i)
            radiobutton_name = sprintf('Joint%d', i);
            button_state = app.(radiobutton_name).SelectedObject.Text;
            if strcmp(button_state,'R')
                state = 1;
            elseif strcmp(button_state,'P')
                state = 2;
            else
                state = 0;
            end
        end

        function change_edit_fields(app,q)
            if strcmp(app.tab_name,'General')
                field_name = 'Edit_J%d';
                len = length(app.DH_table.Data);
            else
                field_name = 'Edit_J%d_kawasaki';
                len = length(app.Kawasaki_DH);
            end

            for i = 1:len
                edit_field_name = sprintf(field_name,i);
                app.(edit_field_name).Value = q(i);
            end 

        end
   
        function J = compute_full_jacobian(app)
            % q - вектор параметров суставов
            % types - вектор, содержащий 'R' для вращательного, 'P' для призматического и 'N' для нейтрального сустава
            joint_types = app.joint_conf;
            % Предварительно выделяем матрицу якобиана
            J = zeros(6, length(joint_types));
        
            % Инициализация преобразования от базы до текущего сустава
            T_end = app.GTMs{end};
        
            for i = 1:length(joint_types)
                % Вычисляем преобразование до следующего сустава
                % Это зависит от вашего подхода к представлению кинематики робота
                T_current = app.GTMs{i}; % Например, используя параметры Денавита-Хартенберга
        
                % Вычисляем ось вращения, направление или нейтральное перемещение для сустава
                % state = 1 -> R; state = 2 -> P; state = 0 -> None
                if joint_types(i) == 1
                    % Вращательный сустав
                    axis = T_current(1:3, 1:3) * [0; 0; 1]; % Пример для Z-оси
                    J(1:3, i) = cross(axis, T_end(1:3, 4) - T_current(1:3, 4)); % Линейная скорость
                    J(4:6, i) = axis; % Угловая скорость
                elseif joint_types(i) == 2
                    % Призматический сустав
                    direction = T_current(1:3, 1:3) * [0; 0; 1]; % Пример для Z-оси
                    J(1:3, i) = direction; % Линейная скорость
                    J(4:6, i) = [0; 0; 0]; % Угловая скорость отсутствует
                elseif joint_types(i) == 0
                    % Нейтральное звено
                    % Для нейтрального звена вращение такое же как у предыдущего звена,
                    % А линейное перемещение изменяется так же, как у вращательного сустава
                    axis = T_current(1:3, 1:3) * [0; 0; 1]; % Пример для Z-оси
                    J(1:3, i) = cross(axis, T_end(1:3, 4) - T_current(1:3, 4)); % Линейная скорость
                    J(4:6, i) = J(4:6, i-1); % Угловая скорость такая же, что и у предыдущего звена
                    
                end
            end
            app.Jacobian = J;
        end

        function initial_joint_confs = get_edit_fields(app)
            if strcmp(app.tab_name,'General')
                initial_joint_confs = size(1,length(app.DH_table.Data));
                field_name = 'Edit_J%d';
                len = length(app.DH_table.Data);
            else
                initial_joint_confs = size(1,length(app.Kawasaki_DH));
                field_name = 'Edit_J%d_kawasaki';
                len = length(app.Kawasaki_DH);
            end
            for i = 1:len
                edit_field_name = sprintf(field_name,i);
                initial_joint_confs(i) = app.(edit_field_name).Value;
            end 
        end
        
        function [alpha,beta,gamma,pos] = get_desired_values(app)
            if strcmp(app.tab_name,'General')
                alpha = app.desired_alpha.Value;
                beta = app.desired_beta.Value;
                gamma = app.desired_gamma.Value;
                pos = [app.desired_x.Value, app.desired_y.Value, app.desired_z.Value]';
            else
                alpha = app.desired_alpha_kawasaki.Value;
                beta = app.desired_beta_kawasaki.Value;
                gamma = app.desired_gamma_kawasaki.Value;
                pos = [app.desired_x_kawasaki.Value, app.desired_y_kawasaki.Value, app.desired_z_kawasaki.Value]';
            end
            app.desired_point = pos;
        end


        function inverse_kinematics_gradient_descent(app)
            % Параметры алгоритма
            % Adaptive Learning Rate
            lr = 3e-4; % Скорость обучения
            max_iters = 7000; % Максимальное количество итераций
            threshold = 1e-3; % Порог сходимости
            [alpha,beta,gamma,pos] = get_desired_values(app);
            % error_list = [];
            app.end_effector_pos = app.GTMs{end}(1:3,4);
            T_k = forward_kinematics(app);
            % Желаемое и начальное положение конечного эффектора
            T_d = compute_desired_transformation(app, gamma, beta, alpha, pos); % Установите целевое преобразование конечного эффектора
            q = get_edit_fields(app)';
            for k = 1:max_iters
                % Вычисляем разницу в позициях и ориентациях
                delta_x = compute_pose_error(app,T_d, T_k);
                % error_list(k) = norm(delta_x);
                
                % Проверяем сходимость
                if norm(delta_x) < threshold
                    disp(['Сходимость достигнута на итерации ', num2str(k)]);
                    break;
                end
                % Вычисляем изменение конфигурации суставов
                delta_q = compute_joint_update(app,delta_x);
                % plot(k,delta_q(1));
                % Обновляем конфигурацию суставов
                q = q + lr * delta_q;
                % Проверка на то, что углы больше 180 или меньше -180
                q(q > 180) = 180; q(q < -180) = -180;
                lr = 0.6 * cos((k/max_iters));  % Adaptive learning rate
                change_edit_fields(app,q);
                % Вычисляем прямую кинематику для текущего q
                T_k = forward_kinematics(app);  % There is an error here
                app.end_effector_pos(:,end + 1) = T_k(1:3, 4);
            end
            
            % Выводим конечное положение суставов
            disp(['Конечная конфигурация суставов: ', num2str(q')])
            % plot(error_list,'LineWidth',2);
            app.calculateButtonPushed()
            norm(delta_x)
        end

        function delta_x = compute_pose_error(~,T_d, T_k)
            % Вычисление позиционной ошибки
            p_error = T_d(1:3, 4) - T_k(1:3, 4);
            
            % Вычисление ориентационной ошибки
            % Ориентационная ошибка может быть выражена через углы Эйлера, ось и угол вращения, или кватернионы.
            % Здесь мы используем упрощение, предполагая, что R_d и R_k близки друг к другу, и можно использовать малые угловые приближения.
            R_error = T_d(1:3, 1:3)' * T_k(1:3, 1:3) - eye(3);
            o_error = [R_error(3, 2); R_error(1, 3); R_error(2, 1)]; % Вектор угловых ошибок
            
            % Объединяем позиционную и ориентационную ошибки в один вектор
            delta_x = [p_error; o_error];
        end

        function delta_q = compute_joint_update(app,delta_x)
            % Получение якобиана от прямой кинематики
            J = app.Jacobian;
        
            % Проверяем, квадратная ли матрица и полного ли она ранга
            if size(J, 1) == size(J, 2) && rank(J) == size(J, 1)
                J_inv = inv(J); % Обычное обращение для квадратной невырожденной матрицы
            else
                J_inv = pinv(J); % Псевдообратная для прямоугольной или вырожденной матрицы
            end
        
            % Вычисление изменения конфигурации суставов
            delta_q = J_inv * delta_x;
        end

        function T_desired = compute_desired_transformation(~, gamma, beta, alpha, pos)
            % For this function RPY angle set was used
            % R B relative to A
            r11 = cosd(alpha) * cosd(beta);
            r12 = cosd(alpha) * sind(beta) * sind(gamma) - sind(alpha) * cosd(gamma);
            r13 = cosd(alpha) * sind(beta) * cosd(gamma) + sind(alpha) * sind(gamma);
            r21 = sind(alpha) * cosd(beta);
            r22 = sind(alpha) * sind(beta) * sind(gamma) + cosd(alpha) * cosd(gamma);
            r23 = sind(alpha) * sind(beta) * cosd(gamma) - cosd(alpha) * sind(gamma);
            r31 = -sind(beta);
            r32 = cosd(beta) * sind(gamma);
            r33 = cosd(beta) * cosd(gamma);
            Rxyz = [r11 r12 r13;
                    r21 r22 r23;
                    r31 r32 r33];
            T_desired = [Rxyz pos;
                          0 0 0 1];
        end

        function run_code(app)
            forward_kinematics(app);
            joint_points = calculate_joint_points(app);
            plot_graph(app, joint_points);

            compute_full_jacobian(app);
            assignin('base', 'J', app.Jacobian);  % to Workspace
            if strcmp(app.tab_name,'General')
                app.Jacobian_table.Data = app.Jacobian;
                app.End_effector_table.Data = app.GTMs{end};
            else
                app.Jacobian_table_Kawasaki.Data = app.Jacobian;
                app.End_effector_table_Kawasaki.Data = app.GTMs{end};
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % initial_DH_table = readtable("initial_DH_table.txt");
            Initial_DH = zeros(8,4);
            app.DH_table.Data = Initial_DH;
            % s = uistyle("BackgroundColor","black","FontColor","white");
            % addStyle(app.DH_table,s,"column",1);
            app.Kawasaki_DH = [0    0    430     0;
                               90   165  0     90;
                               0    820  0     0;
                               90   210  1030  0;
                               -90  0    0     0;
                               90   0    115   0];
            app.DH_table_kawasaki.Data = app.Kawasaki_DH;    
            app.CalculateInverseButton.Visible = 'off';
            app.CalculateInverseButton_kawasaki.Visible = 'off';
        end

        % Button pushed function: calculate
        function calculateButtonPushed(app, event)
            % assignin('base', 'DH_parameters',app.DH_table.Data);  % to Workspace
            % app.tab_name = 'General';
            run_code(app);
            app.CalculateInverseButton.Visible = 'on';
            app.CalculateInverseButton_kawasaki.Visible = 'off';
        end

        % Value changing function: Slider_J1
        function Slider_J1ValueChanging(app, event)
            changingValue = event.Value;
            app.Edit_J1.Value = changingValue;
            run_code(app);
        end

        % Value changed function: Edit_J1
        function Edit_J1ValueChanged(app, event)
            value = app.Edit_J1.Value;
            app.Slider_J1.Value = value;
            run_code(app);
        end

        % Value changing function: Slider_J2
        function Slider_J2ValueChanging(app, event)
            changingValue = event.Value;
            app.Edit_J2.Value = changingValue;
            run_code(app);
        end

        % Value changed function: Edit_J2
        function Edit_J2ValueChanged(app, event)
            value = app.Edit_J2.Value;
            app.Slider_J2.Value = value;
            run_code(app);
        end

        % Value changing function: Slider_J7
        function Slider_J7ValueChanging(app, event)
            changingValue = event.Value;
            app.Edit_J7.Value = changingValue;
            run_code(app);
        end

        % Value changing function: Slider_J8
        function Slider_J8ValueChanging(app, event)
            changingValue = event.Value;
            app.Edit_J8.Value = changingValue;
            run_code(app);
        end

        % Value changing function: Slider_J3
        function Slider_J3ValueChanging(app, event)
            changingValue = event.Value;
            app.Edit_J3.Value = changingValue;
            run_code(app);
        end

        % Value changing function: Slider_J4
        function Slider_J4ValueChanging(app, event)
            changingValue = event.Value;
            app.Edit_J4.Value = changingValue;
            run_code(app);
        end

        % Value changing function: Slider_J5
        function Slider_J5ValueChanging(app, event)
            changingValue = event.Value;
            app.Edit_J5.Value = changingValue;
            run_code(app);
        end

        % Value changing function: Slider_J6
        function Slider_J6ValueChanging(app, event)
            changingValue = event.Value;
            app.Edit_J6.Value = changingValue;
            run_code(app);
        end

        % Value changed function: Edit_J3
        function Edit_J3ValueChanged(app, event)
            value = app.Edit_J3.Value;
            app.Slider_J3.Value = value;
            run_code(app);
        end

        % Value changed function: Edit_J4
        function Edit_J4ValueChanged(app, event)
            value = app.Edit_J4.Value;
            app.Slider_J4.Value = value;
            run_code(app);
        end

        % Value changed function: Edit_J5
        function Edit_J5ValueChanged(app, event)
            value = app.Edit_J5.Value;
            app.Slider_J5.Value = value;
            run_code(app);
        end

        % Value changed function: Edit_J6
        function Edit_J6ValueChanged(app, event)
            value = app.Edit_J6.Value;
            app.Slider_J6.Value = value;
            run_code(app);
        end

        % Value changed function: Edit_J7
        function Edit_J7ValueChanged(app, event)
            value = app.Edit_J7.Value;
            app.Slider_J7.Value = value;
            run_code(app);
        end

        % Value changed function: Edit_J8
        function Edit_J8ValueChanged(app, event)
            value = app.Edit_J8.Value;
            app.Slider_J8.Value = value;
            run_code(app);
        end

        % Value changed function: ResetButton
        function ResetButtonValueChanged(app, event)
            for i=1:8
                slider_name = sprintf('Slider_J%d',i);
                edit_name = sprintf('Edit_J%d',i);
                app.(edit_name).Value = 0;
                app.(slider_name).Value = 0;
                % DefaultRadioButton = sprintf('Joint%d', i);
                % app.(DefaultRadioButton).SelectedObject = 'N';
                startupFcn(app);
                app.UIAxes.reset;
                app.counter = 0;
            end
        end

        % Value changed function: CalculateInverseButton
        function CalculateInverseButtonValueChanged(app, event)
            % value = app.CalculateInverseButton.Value;
            inverse_kinematics_gradient_descent(app); 
            app.counter = 1;
            calculateButtonPushed(app);
        end

        % Button pushed function: upload
        function uploadButtonPushed(app, event)
            % app.tab_name = 'Kawasaki';
            run_code(app);
            app.CalculateInverseButton_kawasaki.Visible = 'on';
            app.CalculateInverseButton.Visible = 'off';
            % Simscape Integration
            % options = simset('SrcWorkspace','current');
            % sim('Kawasaki_BA006L.slx',[],options);
            simulinkModelPath = ('Kawasaki_BA006L.slx');             
            open_system(simulinkModelPath);
        end

        % Value changing function: Knob_1
        function Knob_1ValueChanging(app, event)
            changingValue = event.Value;
            app.Edit_J1_kawasaki.Value = changingValue;
            run_code(app);
            set_param('Kawasaki_BA006L/Slider_J1','Gain',num2str(changingValue));
        end

        % Value changing function: Knob_2
        function Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            app.Edit_J2_kawasaki.Value = changingValue;
            run_code(app);
            set_param('Kawasaki_BA006L/Slider_J2','Gain',num2str(changingValue));
        end

        % Value changing function: Knob_3
        function Knob_3ValueChanging(app, event)
            changingValue = event.Value;
            app.Edit_J3_kawasaki.Value = changingValue;
            run_code(app);
            set_param('Kawasaki_BA006L/Slider_J3','Gain',num2str(changingValue));
        end

        % Value changing function: Knob_4
        function Knob_4ValueChanging(app, event)
            changingValue = event.Value;
            app.Edit_J4_kawasaki.Value = changingValue;
            run_code(app);
            set_param('Kawasaki_BA006L/Slider_J4','Gain',num2str(changingValue));
        end

        % Value changing function: Knob_5
        function Knob_5ValueChanging(app, event)
            changingValue = event.Value;
            app.Edit_J5_kawasaki.Value = changingValue;
            run_code(app);
            set_param('Kawasaki_BA006L/Slider_J5','Gain',num2str(changingValue));
        end

        % Value changing function: Knob_6
        function Knob_6ValueChanging(app, event)
            changingValue = event.Value;
            app.Edit_J6_kawasaki.Value = changingValue;
            run_code(app);
            set_param('Kawasaki_BA006L/Slider_J6','Gain',num2str(changingValue));
        end

        % Value changed function: Edit_J1_kawasaki
        function Edit_J1_kawasakiValueChanged(app, event)
            value = app.Edit_J1_kawasaki.Value;
            app.Knob_1.Value = value;
            run_code(app);
            set_param('Kawasaki_BA006L/Slider_J1','Gain',num2str(value));
        end

        % Value changed function: Edit_J2_kawasaki
        function Edit_J2_kawasakiValueChanged(app, event)
            value = app.Edit_J2_kawasaki.Value;
            app.Knob_2.Value = value;
            run_code(app);
            set_param('Kawasaki_BA006L/Slider_J2','Gain',num2str(value));
        end

        % Value changed function: Edit_J3_kawasaki
        function Edit_J3_kawasakiValueChanged(app, event)
            value = app.Edit_J3_kawasaki.Value;
            app.Knob_3.Value = value;
            run_code(app);
            set_param('Kawasaki_BA006L/Slider_J3','Gain',num2str(value));
        end

        % Value changed function: Edit_J4_kawasaki
        function Edit_J4_kawasakiValueChanged(app, event)
            value = app.Edit_J4_kawasaki.Value;
            app.Knob_4.Value = value;
            run_code(app);
            set_param('Kawasaki_BA006L/Slider_J4','Gain',num2str(value));
        end

        % Value changed function: Edit_J5_kawasaki
        function Edit_J5_kawasakiValueChanged(app, event)
            value = app.Edit_J5_kawasaki.Value;
            app.Knob_5.Value = value;
            run_code(app);
            set_param('Kawasaki_BA006L/Slider_J5','Gain',num2str(value));
        end

        % Value changed function: Edit_J6_kawasaki
        function Edit_J6_kawasakiValueChanged(app, event)
            value = app.Edit_J6_kawasaki.Value;
            app.Knob_6.Value = value;
            run_code(app);
            set_param('Kawasaki_BA006L/Slider_J6','Gain',num2str(value));
        end

        % Button down function: GeneralTab
        function GeneralTabButtonDown(app, event)
            app.tab_name = 'General';
            app.counter = 0;
        end

        % Button down function: KawasakiB006LTab
        function KawasakiB006LTabButtonDown(app, event)
            app.tab_name = 'Kawasaki';
            app.counter = 0;
        end

        % Value changed function: CalculateInverseButton_kawasaki
        function CalculateInverseButton_kawasakiValueChanged(app, event)
            % value = app.CalculateInverseButton_kawasaki.Value;
            inverse_kinematics_gradient_descent(app); 
            app.counter = 1;
            uploadButtonPushed(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.GeneralTab.ButtonDownFcn = createCallbackFcn(app, @GeneralTabButtonDown, true);
            app.UIAxes = uiaxes(app.GeneralTab);
            title(app.UIAxes, 'Robot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DHtablePanel = uipanel(app.GeneralTab);
            app.DHtablePanel.Title = 'D-H table';
            app.DH_table = uitable(app.DHtablePanel);
            app.DH_table.ColumnName = {'α_(i-1)'; 'a_(i-1)'; 'd_i'; 'θ_i'};
            app.DH_table.ColumnEditable = true;
            app.Joint1 = uibuttongroup(app.DHtablePanel);
            app.RButton = uiradiobutton(app.Joint1);
            app.RButton.Text = 'R';
            app.PButton = uiradiobutton(app.Joint1);
            app.PButton.Text = 'P';
            app.NButton_1 = uiradiobutton(app.Joint1);
            app.NButton_1.Text = 'N';
            app.NButton_1.Value = true;
            app.Joint2 = uibuttongroup(app.DHtablePanel);
            app.RButton_2 = uiradiobutton(app.Joint2);
            app.RButton_2.Text = 'R';
            app.PButton_2 = uiradiobutton(app.Joint2);
            app.PButton_2.Text = 'P';
            app.NButton_2 = uiradiobutton(app.Joint2);
            app.NButton_2.Text = 'N';
            app.NButton_2.Value = true;
            app.Joint3 = uibuttongroup(app.DHtablePanel);
            app.RButton_3 = uiradiobutton(app.Joint3);
            app.RButton_3.Text = 'R';
            app.PButton_3 = uiradiobutton(app.Joint3);
            app.PButton_3.Text = 'P';
            app.NButton_3 = uiradiobutton(app.Joint3);
            app.NButton_3.Text = 'N';
            app.NButton_3.Value = true;
            app.Joint4 = uibuttongroup(app.DHtablePanel);
            app.RButton_4 = uiradiobutton(app.Joint4);
            app.RButton_4.Text = 'R';
            app.PButton_4 = uiradiobutton(app.Joint4);
            app.PButton_4.Text = 'P';
            app.NButton_4 = uiradiobutton(app.Joint4);
            app.NButton_4.Text = 'N';
            app.NButton_4.Value = true;
            app.Joint5 = uibuttongroup(app.DHtablePanel);
            app.RButton_5 = uiradiobutton(app.Joint5);
            app.RButton_5.Text = 'R';
            app.PButton_5 = uiradiobutton(app.Joint5);
            app.PButton_5.Text = 'P';
            app.NButton_5 = uiradiobutton(app.Joint5);
            app.NButton_5.Text = 'N';
            app.NButton_5.Value = true;
            app.Joint6 = uibuttongroup(app.DHtablePanel);
            app.RButton_6 = uiradiobutton(app.Joint6);
            app.RButton_6.Text = 'R';
            app.PButton_6 = uiradiobutton(app.Joint6);
            app.PButton_6.Text = 'P';
            app.NButton_6 = uiradiobutton(app.Joint6);
            app.NButton_6.Text = 'N';
            app.NButton_6.Value = true;
            app.Joint7 = uibuttongroup(app.DHtablePanel);
            app.RButton_7 = uiradiobutton(app.Joint7);
            app.RButton_7.Text = 'R';
            app.PButton_7 = uiradiobutton(app.Joint7);
            app.PButton_7.Text = 'P';
            app.NButton_7 = uiradiobutton(app.Joint7);
            app.NButton_7.Text = 'N';
            app.NButton_7.Value = true;
            app.Joint8 = uibuttongroup(app.DHtablePanel);
            app.RButton_8 = uiradiobutton(app.Joint8);
            app.RButton_8.Text = 'R';
            app.PButton_8 = uiradiobutton(app.Joint8);
            app.PButton_8.Text = 'P';
            app.NButton_8 = uiradiobutton(app.Joint8);
            app.NButton_8.Text = 'N';
            app.NButton_8.Value = true;
            app.Slider_J1 = uislider(app.DHtablePanel);
            app.Slider_J1.Limits = [-180 180];
            app.Slider_J1.ValueChangingFcn = createCallbackFcn(app, @Slider_J1ValueChanging, true);
            app.Slider_J2 = uislider(app.DHtablePanel);
            app.Slider_J2.Limits = [-180 180];
            app.Slider_J2.ValueChangingFcn = createCallbackFcn(app, @Slider_J2ValueChanging, true);
            app.Slider_J3 = uislider(app.DHtablePanel);
            app.Slider_J3.Limits = [-180 180];
            app.Slider_J3.ValueChangingFcn = createCallbackFcn(app, @Slider_J3ValueChanging, true);
            app.Slider_J4 = uislider(app.DHtablePanel);
            app.Slider_J4.Limits = [-180 180];
            app.Slider_J4.ValueChangingFcn = createCallbackFcn(app, @Slider_J4ValueChanging, true);
            app.Slider_J5 = uislider(app.DHtablePanel);
            app.Slider_J5.Limits = [-180 180];
            app.Slider_J5.ValueChangingFcn = createCallbackFcn(app, @Slider_J5ValueChanging, true);
            app.Slider_J6 = uislider(app.DHtablePanel);
            app.Slider_J6.Limits = [-180 180];
            app.Slider_J6.ValueChangingFcn = createCallbackFcn(app, @Slider_J6ValueChanging, true);
            app.Slider_J7 = uislider(app.DHtablePanel);
            app.Slider_J7.Limits = [-180 180];
            app.Slider_J7.ValueChangingFcn = createCallbackFcn(app, @Slider_J7ValueChanging, true);
            app.Slider_J8 = uislider(app.DHtablePanel);
            app.Slider_J8.Limits = [-180 180];
            app.Slider_J8.ValueChangingFcn = createCallbackFcn(app, @Slider_J8ValueChanging, true);
            app.Edit_J1 = uieditfield(app.DHtablePanel, 'numeric');
            app.Edit_J1.ValueChangedFcn = createCallbackFcn(app, @Edit_J1ValueChanged, true);
            app.Edit_J2 = uieditfield(app.DHtablePanel, 'numeric');
            app.Edit_J2.ValueChangedFcn = createCallbackFcn(app, @Edit_J2ValueChanged, true);
            app.Edit_J3 = uieditfield(app.DHtablePanel, 'numeric');
            app.Edit_J3.ValueChangedFcn = createCallbackFcn(app, @Edit_J3ValueChanged, true);
            app.Edit_J4 = uieditfield(app.DHtablePanel, 'numeric');
            app.Edit_J4.ValueChangedFcn = createCallbackFcn(app, @Edit_J4ValueChanged, true);
            app.Edit_J5 = uieditfield(app.DHtablePanel, 'numeric');
            app.Edit_J5.ValueChangedFcn = createCallbackFcn(app, @Edit_J5ValueChanged, true);
            app.Edit_J6 = uieditfield(app.DHtablePanel, 'numeric');
            app.Edit_J6.ValueChangedFcn = createCallbackFcn(app, @Edit_J6ValueChanged, true);
            app.Edit_J7 = uieditfield(app.DHtablePanel, 'numeric');
            app.Edit_J7.ValueChangedFcn = createCallbackFcn(app, @Edit_J7ValueChanged, true);
            app.Edit_J8 = uieditfield(app.DHtablePanel, 'numeric');
            app.Edit_J8.ValueChangedFcn = createCallbackFcn(app, @Edit_J8ValueChanged, true);
            app.calculate = uibutton(app.DHtablePanel, 'push');
            app.calculate.ButtonPushedFcn = createCallbackFcn(app, @calculateButtonPushed, true);
            app.calculate.Text = 'Calculate';
            app.ResetButton = uibutton(app.DHtablePanel, 'state');
            app.ResetButton.ValueChangedFcn = createCallbackFcn(app, @ResetButtonValueChanged, true);
            app.ResetButton.Text = 'Reset Values';
            app.JacobianLabel = uilabel(app.GeneralTab);
            app.JacobianLabel.Text = 'Jacobian:';
            app.EndeffectorspositionandorientationLabel = uilabel(app.GeneralTab);
            app.EndeffectorspositionandorientationLabel.Text = 'End–effector''s position and orientation:';
            app.Jacobian_table = uitable(app.GeneralTab);
            app.Jacobian_table.ColumnName = '';
            app.Jacobian_table.RowName = {};
            app.End_effector_table = uitable(app.GeneralTab);
            app.End_effector_table.ColumnName = '';
            app.End_effector_table.RowName = {};
            app.DesiredpositionandorientationLabel = uilabel(app.GeneralTab);
            app.DesiredpositionandorientationLabel.Text = 'Desired position and orientation:';
            app.alphaEditFieldLabel = uilabel(app.GeneralTab);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.desired_alpha = uieditfield(app.GeneralTab, 'numeric');
            app.betaEditFieldLabel = uilabel(app.GeneralTab);
            app.betaEditFieldLabel.Text = 'beta';
            app.desired_beta = uieditfield(app.GeneralTab, 'numeric');
            app.gammaEditFieldLabel = uilabel(app.GeneralTab);
            app.gammaEditFieldLabel.Text = 'gamma';
            app.desired_gamma = uieditfield(app.GeneralTab, 'numeric');
            app.xEditFieldLabel = uilabel(app.GeneralTab);
            app.xEditFieldLabel.Text = 'x';
            app.desired_x = uieditfield(app.GeneralTab, 'numeric');
            app.yEditFieldLabel = uilabel(app.GeneralTab);
            app.yEditFieldLabel.Text = 'y';
            app.desired_y = uieditfield(app.GeneralTab, 'numeric');
            app.zEditFieldLabel = uilabel(app.GeneralTab);
            app.zEditFieldLabel.Text = 'z';
            app.desired_z = uieditfield(app.GeneralTab, 'numeric');
            app.CalculateInverseButton = uibutton(app.GeneralTab, 'state');
            app.CalculateInverseButton.ValueChangedFcn = createCallbackFcn(app, @CalculateInverseButtonValueChanged, true);
            app.CalculateInverseButton.Text = 'Calculate Inverse';
            app.KawasakiB006LTab = uitab(app.TabGroup);
            app.KawasakiB006LTab.Title = 'Kawasaki B006L';
            app.KawasakiB006LTab.ButtonDownFcn = createCallbackFcn(app, @KawasakiB006LTabButtonDown, true);
            app.UIAxes_Kawasaki = uiaxes(app.KawasakiB006LTab);
            title(app.UIAxes_Kawasaki, 'Robot')
            xlabel(app.UIAxes_Kawasaki, 'X')
            ylabel(app.UIAxes_Kawasaki, 'Y')
            zlabel(app.UIAxes_Kawasaki, 'Z')
            app.DHtablePanel_Kawasaki = uipanel(app.KawasakiB006LTab);
            app.DHtablePanel_Kawasaki.Title = 'D-H table';
            app.DH_table_kawasaki = uitable(app.DHtablePanel_Kawasaki);
            app.DH_table_kawasaki.ColumnName = {'α_(i-1)'; 'a_(i-1)'; 'd_i'; 'θ_i'};
            app.DH_table_kawasaki.RowName = {};
            app.DH_table_kawasaki.ColumnEditable = true;
            app.Edit_J1_kawasaki = uieditfield(app.DHtablePanel_Kawasaki, 'numeric');
            app.Edit_J1_kawasaki.ValueChangedFcn = createCallbackFcn(app, @Edit_J1_kawasakiValueChanged, true);
            app.Edit_J2_kawasaki = uieditfield(app.DHtablePanel_Kawasaki, 'numeric');
            app.Edit_J2_kawasaki.ValueChangedFcn = createCallbackFcn(app, @Edit_J2_kawasakiValueChanged, true);
            app.Edit_J3_kawasaki = uieditfield(app.DHtablePanel_Kawasaki, 'numeric');
            app.Edit_J3_kawasaki.ValueChangedFcn = createCallbackFcn(app, @Edit_J3_kawasakiValueChanged, true);
            app.Edit_J4_kawasaki = uieditfield(app.DHtablePanel_Kawasaki, 'numeric');
            app.Edit_J4_kawasaki.ValueChangedFcn = createCallbackFcn(app, @Edit_J4_kawasakiValueChanged, true);
            app.Edit_J5_kawasaki = uieditfield(app.DHtablePanel_Kawasaki, 'numeric');
            app.Edit_J5_kawasaki.ValueChangedFcn = createCallbackFcn(app, @Edit_J5_kawasakiValueChanged, true);
            app.Edit_J6_kawasaki = uieditfield(app.DHtablePanel_Kawasaki, 'numeric');
            app.Edit_J6_kawasaki.ValueChangedFcn = createCallbackFcn(app, @Edit_J6_kawasakiValueChanged, true);
            app.upload = uibutton(app.DHtablePanel_Kawasaki, 'push');
            app.upload.ButtonPushedFcn = createCallbackFcn(app, @uploadButtonPushed, true);
            app.upload.Text = 'Upload';
            app.KnobLabel = uilabel(app.DHtablePanel_Kawasaki);
            app.KnobLabel.Text = 'θ1';
            app.Knob_1 = uiknob(app.DHtablePanel_Kawasaki, 'continuous');
            app.Knob_1.Limits = [-180 180];
            app.Knob_1.ValueChangingFcn = createCallbackFcn(app, @Knob_1ValueChanging, true);
            app.Knob_2Label = uilabel(app.DHtablePanel_Kawasaki);
            app.Knob_2Label.Text = 'θ2';
            app.Knob_2 = uiknob(app.DHtablePanel_Kawasaki, 'continuous');
            app.Knob_2.Limits = [-180 180];
            app.Knob_2.ValueChangingFcn = createCallbackFcn(app, @Knob_2ValueChanging, true);
            app.Knob_3Label = uilabel(app.DHtablePanel_Kawasaki);
            app.Knob_3Label.Text = 'θ3';
            app.Knob_3 = uiknob(app.DHtablePanel_Kawasaki, 'continuous');
            app.Knob_3.Limits = [-180 180];
            app.Knob_3.ValueChangingFcn = createCallbackFcn(app, @Knob_3ValueChanging, true);
            app.Knob_4Label = uilabel(app.DHtablePanel_Kawasaki);
            app.Knob_4Label.Text = 'θ4';
            app.Knob_4 = uiknob(app.DHtablePanel_Kawasaki, 'continuous');
            app.Knob_4.Limits = [-180 180];
            app.Knob_4.ValueChangingFcn = createCallbackFcn(app, @Knob_4ValueChanging, true);
            app.Knob_5Label = uilabel(app.DHtablePanel_Kawasaki);
            app.Knob_5Label.Text = 'θ5';
            app.Knob_5 = uiknob(app.DHtablePanel_Kawasaki, 'continuous');
            app.Knob_5.Limits = [-180 180];
            app.Knob_5.ValueChangingFcn = createCallbackFcn(app, @Knob_5ValueChanging, true);
            app.Knob_6Label = uilabel(app.DHtablePanel_Kawasaki);
            app.Knob_6Label.Text = 'θ6';
            app.Knob_6 = uiknob(app.DHtablePanel_Kawasaki, 'continuous');
            app.Knob_6.Limits = [-180 180];
            app.Knob_6.ValueChangingFcn = createCallbackFcn(app, @Knob_6ValueChanging, true);
            app.End_effector_table_Kawasaki = uitable(app.KawasakiB006LTab);
            app.End_effector_table_Kawasaki.ColumnName = '';
            app.End_effector_table_Kawasaki.RowName = {};
            app.EndeffectorspositionandorientationLabel_2 = uilabel(app.KawasakiB006LTab);
            app.EndeffectorspositionandorientationLabel_2.Text = 'End–effector''s position and orientation:';
            app.JacobianLabel_2 = uilabel(app.KawasakiB006LTab);
            app.JacobianLabel_2.Text = 'Jacobian:';
            app.Jacobian_table_Kawasaki = uitable(app.KawasakiB006LTab);
            app.Jacobian_table_Kawasaki.ColumnName = '';
            app.Jacobian_table_Kawasaki.RowName = {};
            app.DesiredpositionandorientationLabel_2 = uilabel(app.KawasakiB006LTab);
            app.DesiredpositionandorientationLabel_2.Text = 'Desired position and orientation:';
            app.CalculateInverseButton_kawasaki = uibutton(app.KawasakiB006LTab, 'state');
            app.CalculateInverseButton_kawasaki.ValueChangedFcn = createCallbackFcn(app, @CalculateInverseButton_kawasakiValueChanged, true);
            app.CalculateInverseButton_kawasaki.Text = 'Calculate Inverse';
            app.alphaEditFieldLabel_2 = uilabel(app.KawasakiB006LTab);
            app.alphaEditFieldLabel_2.Text = 'alpha';
            app.desired_alpha_kawasaki = uieditfield(app.KawasakiB006LTab, 'numeric');
            app.betaEditFieldLabel_2 = uilabel(app.KawasakiB006LTab);
            app.betaEditFieldLabel_2.Text = 'beta';
            app.desired_beta_kawasaki = uieditfield(app.KawasakiB006LTab, 'numeric');
            app.gammaEditFieldLabel_2 = uilabel(app.KawasakiB006LTab);
            app.gammaEditFieldLabel_2.Text = 'gamma';
            app.desired_gamma_kawasaki = uieditfield(app.KawasakiB006LTab, 'numeric');
            app.xEditFieldLabel_2 = uilabel(app.KawasakiB006LTab);
            app.xEditFieldLabel_2.Text = 'x';
            app.desired_x_kawasaki = uieditfield(app.KawasakiB006LTab, 'numeric');
            app.yEditFieldLabel_2 = uilabel(app.KawasakiB006LTab);
            app.yEditFieldLabel_2.Text = 'y';
            app.desired_y_kawasaki = uieditfield(app.KawasakiB006LTab, 'numeric');
            app.zEditFieldLabel_2 = uilabel(app.KawasakiB006LTab);
            app.zEditFieldLabel_2.Text = 'z';
            app.desired_z_kawasaki = uieditfield(app.KawasakiB006LTab, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_v4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end